classdef Controlling_DC_motor_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ConnectionTab                matlab.ui.container.Tab
        ConfigrationPanel            matlab.ui.container.Panel
        BoardsDropDown               matlab.ui.control.DropDown
        BoardLabel                   matlab.ui.control.Label
        AvailablePortsDropDown       matlab.ui.control.DropDown
        AvailablePortsDropDownLabel  matlab.ui.control.Label
        SelectYourBoardLabel         matlab.ui.control.Label
        SelectYourPortLabel          matlab.ui.control.Label
        TestingPanel                 matlab.ui.container.Panel
        TestBlinkD13Button           matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        ConnectionPanel              matlab.ui.container.Panel
        DisconnectButton             matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        StatusPanel                  matlab.ui.container.Panel
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        StatusNotconnectedLabel      matlab.ui.control.Label
        MotorControlTab              matlab.ui.container.Tab
        SystemMonitoringPanel        matlab.ui.container.Panel
        SaveDataButton               matlab.ui.control.Button
        PWMLabel                     matlab.ui.control.Label
        ErrorLabel                   matlab.ui.control.Label
        ActualRPMLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        SystemControllingPanel       matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        DirectionDropDown            matlab.ui.control.DropDown
        DirectionDropDownLabel       matlab.ui.control.Label
        RPMTargetSpinner             matlab.ui.control.Spinner
        RPMTargetSpinnerLabel        matlab.ui.control.Label
        KdSpinner                    matlab.ui.control.Spinner
        KdSpinnerLabel               matlab.ui.control.Label
        KiSpinner                    matlab.ui.control.Spinner
        KiSpinnerLabel               matlab.ui.control.Label
        KpSpinner                    matlab.ui.control.Spinner
        KpSpinnerLabel               matlab.ui.control.Label
    end


    properties (Access = private)
        hwConnected (1,1) logical = false
        lastCount   (1,1) double  = 0

        % ===== Hardware =====
        a
        enc

        % ===== Pins / Settings =====
        COM   = '/dev/ttyUSB0';
        BOARD = 'Nano3';

        R_PWM = 'D9';
        L_PWM = 'D10';
        R_EN  = 'D8';
        L_EN  = 'D7';

        R_IS  = 'A0';   % optional
        L_IS  = 'A1';   % optional

        ENC_A = 'D2';
        ENC_B = 'D3';

        CPR_MOTOR_4X  = 64.0
        GEAR_RATIO    = 131.25
        RPM_AT_OUTPUT = true
        CPR_TOTAL

        SAMPLE_MS = 50
        TsTarget

        PWM_MIN = 0
        PWM_MAX = 255

        lastPWM (1,1) double = 0

        % Plot buffers
        bufMax (1,1) double = 600
        tBuf
        targetBuf
        rpmBuf
        errBuf
        pwmBuf

        % Line handles
        lineTarget
        lineRPM
        lineErr
        linePWM

        % ===== Control variables (linked to UI) =====
        SETPOINT_RPM = 50.0
        forwardDir   = true   % true=Forward, false=Reverse

        % ===== PID gains =====
        Kp = 4.0
        Ki = 4.0
        Kd = 0.01

        rpmAlpha = 0.287500000
        dAlpha   = 0.060000000
        I_LIMIT  = 200.0

        SOFTSTART_SEC = 0.4
        SOFTSTART_PWM = 50

        % ===== Runtime states =====
        tmr
        Running = false
        stopFlag = false

        t0
        tPrev
        firstSample = true

        integral  = 0.0
        prevError = 0.0
        dFiltered = 0.0
        rpmFilt   = 0.0

        % PWM ramp (slew-rate limiting)
        pwmSlewUp   (1,1) double = 200
        pwmSlewDown (1,1) double = 400

        % Save/plot trimming
        saveEndIndex (1,1) double = NaN
    end

    methods (Access = private)

        function controlStep(app)
            if ~app.hwConnected || isempty(app.a) || isempty(app.enc)
                return;
            end

            if ~app.Running || app.stopFlag
                return;
            end

            t = toc(app.t0);

            dt = t - app.tPrev;
            app.tPrev = t;
            if dt <= 0 || ~isfinite(dt)
                return;
            end

            % Encoder delta
            count = readCount(app.enc);
            delta = count - app.lastCount;
            app.lastCount = count;

            rpmRaw = (double(delta) * 60.0) / (double(app.CPR_TOTAL) * dt);
            rpmRawMag = abs(rpmRaw);

            % EMA filter
            app.rpmFilt = app.rpmAlpha * rpmRawMag + (1 - app.rpmAlpha) * app.rpmFilt;

            % Initialize to avoid derivative kick
            if app.firstSample
                app.rpmFilt   = rpmRawMag;
                app.prevError = app.SETPOINT_RPM - app.rpmFilt;
                app.dFiltered = 0;
                app.integral  = 0;
                app.firstSample = false;
            end

            % Soft-start + PID
            if t < app.SOFTSTART_SEC
                pwmCmd = app.SOFTSTART_PWM;
                errNow = app.SETPOINT_RPM - app.rpmFilt;
            else
                if app.SETPOINT_RPM < 0.5
                    app.integral  = 0;
                    app.prevError = 0;
                    app.dFiltered = 0;
                    pwmCmd = 0;
                    errNow = 0;
                else
                    errNow = app.SETPOINT_RPM - app.rpmFilt;

                    app.integral = app.integral + errNow * dt;
                    app.integral = max(-app.I_LIMIT, min(app.I_LIMIT, app.integral));

                    d = (errNow - app.prevError) / dt;
                    app.dFiltered = app.dAlpha * d + (1 - app.dAlpha) * app.dFiltered;

                    u = app.Kp * errNow + app.Ki * app.integral + app.Kd * app.dFiltered;
                    pwmCmd = round(u);

                    % Saturation + anti-windup
                    if pwmCmd > app.PWM_MAX
                        pwmCmd = app.PWM_MAX;
                        if errNow > 0
                            app.integral = app.integral - errNow * dt;
                        end
                    elseif pwmCmd < app.PWM_MIN
                        pwmCmd = app.PWM_MIN;
                        if errNow < 0
                            app.integral = app.integral - errNow * dt;
                        end
                    end

                    app.prevError = errNow;
                end
            end

            % Slew-rate limit PWM
            pwmCmd = app.limitPwmSlew(pwmCmd, dt);
            app.applyMotorPWM(pwmCmd);

            if ~app.Running || app.stopFlag
                return;
            end

            % Update Text Indicators
            app.ActualRPMLabel.Text = sprintf("Actual: %.1f RPM", app.rpmFilt);
            app.ErrorLabel.Text     = sprintf("Error: %.1f RPM", errNow);
            app.PWMLabel.Text       = sprintf("PWM: %d / 255", round(app.lastPWM));

            % Update Plot Buffers
            app.tBuf(1:end-1)      = app.tBuf(2:end);
            app.targetBuf(1:end-1) = app.targetBuf(2:end);
            app.rpmBuf(1:end-1)    = app.rpmBuf(2:end);
            app.errBuf(1:end-1)    = app.errBuf(2:end);
            app.pwmBuf(1:end-1)    = app.pwmBuf(2:end);

            app.tBuf(end)      = t;
            app.targetBuf(end) = app.SETPOINT_RPM;
            app.rpmBuf(end)    = app.rpmFilt;
            app.errBuf(end)    = errNow;
            app.pwmBuf(end)    = app.lastPWM;

            % Update Plot Lines
            set(app.lineTarget, "XData", app.tBuf, "YData", app.targetBuf);
            set(app.lineRPM,    "XData", app.tBuf, "YData", app.rpmBuf);
            set(app.lineErr,    "XData", app.tBuf, "YData", app.errBuf);
            set(app.linePWM,    "XData", app.tBuf, "YData", app.pwmBuf);
        end

        function stopNow(app)
            app.stopFlag = true;

            % Save the last valid logged sample index before stop action affects the buffers
            lastValidIdx = find(~isnan(app.tBuf), 1, 'last');
            if isempty(lastValidIdx)
                app.saveEndIndex = NaN;
            else
                app.saveEndIndex = lastValidIdx;
            end

            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    stop(app.tmr);
                catch
                end
            end

            % Motor OFF
            try
                app.applyMotorPWM(0);

                if ~isempty(app.a)
                    writeDigitalPin(app.a, app.R_EN, 0);
                    writeDigitalPin(app.a, app.L_EN, 0);
                end
            catch
            end

            app.Running = false;

            % UI enable/disable
            if ~isempty(app.RunButton) && isvalid(app.RunButton)
                app.RunButton.Enable = "on";
            end
            if ~isempty(app.StopButton) && isvalid(app.StopButton)
                app.StopButton.Enable = "off";
            end

            % Re-enable PID spinners only when motor is stopped
            app.updatePidSpinnerState();
        end

        function applyMotorPWM(app, pwm)
            if ~app.hwConnected || isempty(app.a)
                return;
            end

            pwm = max(app.PWM_MIN, min(app.PWM_MAX, pwm));
            app.lastPWM = pwm;

            duty = pwm / 255;

            % Keep BTS7960 enabled
            writeDigitalPin(app.a, app.R_EN, 1);
            writeDigitalPin(app.a, app.L_EN, 1);

            if pwm == 0
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
                return;
            end

            if app.forwardDir
                % Forward
                writePWMDutyCycle(app.a, app.R_PWM, duty);
                writePWMDutyCycle(app.a, app.L_PWM, 0);
            else
                % Reverse
                writePWMDutyCycle(app.a, app.R_PWM, 0);
                writePWMDutyCycle(app.a, app.L_PWM, duty);
            end
        end

        function initConnectionTab(app)
            % Boards
            app.BoardsDropDown.Items = {'Uno','Nano3','Mega2560','Leonardo','Due'};
            if isempty(app.BoardsDropDown.Value)
                app.BoardsDropDown.Value = 'Nano3';
            end

            % Ports
            app.refreshPorts();

            % Buttons initial state
            app.ConnectButton.Enable        = "on";
            app.DisconnectButton.Enable     = "off";
            app.TestBlinkD13Button.Enable   = "off";

            % Motor controls disabled until connected
            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            app.setStatus("err","Not connected");
        end

        function refreshPorts(app)
            % Store current selection before modifying Items
            current = string(app.AvailablePortsDropDown.Value);

            % Show scanning state (yellow)
            app.setStatus("warn","Scanning ports...");
            drawnow;

            % Small scan window to reduce temporary OS glitches
            scanSec = 0.6;  % adjust if you want (0.3–1.0 is fine)
            tStart  = tic;

            portsAll = strings(1,0);
            while toc(tStart) < scanSec
                portsNow = serialportlist("available");
                portsAll = unique([portsAll, portsNow(:)']); %ok
                pause(0.1);
                drawnow;
            end

            ports = portsAll;

            % Prefer Arduino-like ports
            isCandidate = contains(ports,"/dev/ttyACM") | ...
                contains(ports,"/dev/ttyUSB") | ...
                startsWith(ports,"COM");
            if any(isCandidate)
                ports = ports(isCandidate);
            end

            % Force row format (1xN)
            ports = ports(:)';

            % Case: No ports detected
            if isempty(ports)

                % If already connected, keep current port visible
                if app.hwConnected && strlength(current) > 0
                    app.AvailablePortsDropDown.Items = current;
                    app.AvailablePortsDropDown.Value = current;

                    % Return to connected state (green)
                    app.setStatus("ok","Still connected (no ports detected during scan)");
                else
                    app.AvailablePortsDropDown.Items = "";
                    app.AvailablePortsDropDown.Value = "";
                    app.setStatus("err","No serial ports found");
                end

                return;
            end

            % If connected and current port not in list, preserve it
            if app.hwConnected && strlength(current) > 0 && ~any(ports == current)
                ports = [current ports];
            end

            % Update dropdown items
            app.AvailablePortsDropDown.Items = ports;

            % Restore previous selection if possible
            if strlength(current) > 0 && any(ports == current)
                app.AvailablePortsDropDown.Value = current;
            else
                app.AvailablePortsDropDown.Value = ports(1);
            end

            % Final state after scan:
            % - Green if connected
            % - Red if not connected
            if app.hwConnected
                app.setStatus("ok","Scan complete (connected)");
            else
                app.setStatus("err","Scan complete (not connected)");
            end
        end

        function setStatus(app, state, msg)
            % state: "ok" | "warn" | "err" | "test"
            % Updates status text and lamp color

            app.StatusNotconnectedLabel.Text = "Status: " + string(msg);

            switch lower(string(state))
                case "ok"
                    app.StatusLamp.Color = [0.2 0.8 0.2];     % Green (connected / success)

                case "warn"
                    app.StatusLamp.Color = [0.95 0.75 0.2];   % Yellow (scanning / connecting)

                case "test"
                    app.StatusLamp.Color = [0.2 0.5 0.95];    % Blue (testing)

                otherwise
                    app.StatusLamp.Color = [0.9 0.3 0.3];     % Red (disconnected / error)
            end
        end

        function connectArduino(app)

            app.stopNow();  % Ensure motor stopped

            port  = string(app.AvailablePortsDropDown.Value);
            board = string(app.BoardsDropDown.Value);

            if strlength(port) == 0
                app.setStatus("err","Select a port first");
                return;
            end

            % Disable UI during connection attempt
            app.ConnectButton.Enable      = "off";
            app.DisconnectButton.Enable   = "off";
            app.RefreshButton.Enable      = "off";
            app.TestBlinkD13Button.Enable = "off";

            % Show connecting state (yellow)
            app.setStatus("warn","Connecting to " + port + " (" + board + ") ...");
            drawnow;

            try
                app.COM   = char(port);
                app.BOARD = char(board);

                % ------------------------------------------------------------
                % FAST FAIL: Quick COM probe (catches busy/unavailable port fast)
                % ------------------------------------------------------------
                try
                    tmp = serialport(app.COM, 115200, "Timeout", 0.5);
                    pause(0.05);
                    delete(tmp);
                catch
                    app.setStatus("err","Port cannot be opened (busy/unavailable)");
                    uialert(app.UIFigure, ...
                        "Cannot open the selected COM port." + newline + ...
                        "Possible causes:" + newline + ...
                        "- Port is already in use" + newline + ...
                        "- Driver issue" + newline + ...
                        "- Wrong COM selected", ...
                        "Connection Error");
                    app.afterConnectErrorRefresh();
                    return;
                end

                % ------------------------------------------------------------
                % Real Arduino connection
                % ------------------------------------------------------------
                app.a = arduino(app.COM, app.BOARD, "Libraries","rotaryEncoder");

                % Configure BTS7960 pins
                configurePin(app.a, app.R_PWM, "PWM");
                configurePin(app.a, app.L_PWM, "PWM");
                configurePin(app.a, app.R_EN , "DigitalOutput");
                configurePin(app.a, app.L_EN , "DigitalOutput");

                % Optional current sense pins
                configurePin(app.a, app.R_IS, "AnalogInput");
                configurePin(app.a, app.L_IS, "AnalogInput");

                % Enable BTS7960
                writeDigitalPin(app.a, app.R_EN, 1);
                writeDigitalPin(app.a, app.L_EN, 1);

                % Encoder object
                app.enc = rotaryEncoder(app.a, app.ENC_A, app.ENC_B, round(app.CPR_TOTAL));
                app.lastCount = readCount(app.enc);

                app.hwConnected = true;
                app.applyMotorPWM(0);

                % Connected = green
                app.setStatus("ok","Connected: " + port + " (" + board + ")");

                % Enable UI
                app.ConnectButton.Enable      = "off";
                app.DisconnectButton.Enable   = "on";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "on";
                app.RunButton.Enable          = "on";
                app.StopButton.Enable         = "off";

            catch ME

                % Reset state
                app.hwConnected = false;
                app.a   = [];
                app.enc = [];

                app.setStatus("err","Connection failed");

                % Restore UI (disconnected)
                app.ConnectButton.Enable      = "on";
                app.DisconnectButton.Enable   = "off";
                app.RefreshButton.Enable      = "on";
                app.TestBlinkD13Button.Enable = "off";
                app.RunButton.Enable          = "off";
                app.StopButton.Enable         = "off";

                % Show error
                uialert(app.UIFigure, ...
                    "Unable to connect to the selected board." + newline + ...
                    "MATLAB says: " + string(ME.message) + newline + newline + ...
                    "Try:" + newline + ...
                    "- Select the correct board" + newline + ...
                    "- Select the correct COM port" + newline + ...
                    "- Unplug/plug the Arduino", ...
                    "Connection Error");

                % Soft refresh after showing the error
                app.afterConnectErrorRefresh();
            end
        end

        function disconnectArduino(app)
            app.stopNow();  % Ensure motor is stopped

            % Force BTS7960 outputs OFF before disconnecting hardware
            try
                if ~isempty(app.a)
                    % Disable PWM outputs
                    writePWMDutyCycle(app.a, app.R_PWM, 0);
                    writePWMDutyCycle(app.a, app.L_PWM, 0);

                    % Optional: disable enable pins too
                    writeDigitalPin(app.a, app.R_EN, 0);
                    writeDigitalPin(app.a, app.L_EN, 0);
                end
            catch
            end

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            % UI state
            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";

            app.RunButton.Enable      = "off";
            app.StopButton.Enable     = "off";
            app.SaveDataButton.Enable = "off";

            % Refresh ports after disconnect
            app.refreshPorts();
        end

        function testConnection(app)
            if ~app.hwConnected || isempty(app.a)
                app.setStatus("err","Not connected");
                return;
            end

            % Blue during test
            app.setStatus("test","Testing connection (blink D13) ...");
            drawnow;

            try
                configurePin(app.a,"D13","DigitalOutput");
                writeDigitalPin(app.a,"D13",1); pause(1);
                writeDigitalPin(app.a,"D13",0);

                % Back to green after successful test
                app.setStatus("ok","Test OK (blinked D13)");
            catch ME
                % Red on failure
                app.setStatus("err","Test failed");
                uialert(app.UIFigure, ME.message, "Test Failed");
            end
        end


        function afterConnectErrorRefresh(app)
            % Soft refresh after a connection error:
            % - stop everything
            % - clear hardware
            % - restore UI to disconnected state
            % - rescan ports (yellow during scan, ends red if not connected)

            app.stopNow();

            app.hwConnected = false;
            app.enc = [];
            app.a   = [];

            app.ConnectButton.Enable      = "on";
            app.DisconnectButton.Enable   = "off";
            app.TestBlinkD13Button.Enable = "off";
            app.RefreshButton.Enable      = "on";

            app.RunButton.Enable      = "off";
            app.StopButton.Enable     = "off";
            app.SaveDataButton.Enable = "off";

            app.refreshPorts();
        end

        function clearPlots(app)
            % Clear plot buffers and reset line data

            if isempty(app.UIAxes) || ~isvalid(app.UIAxes)
                return;
            end

            app.tBuf(:)      = nan;
            app.targetBuf(:) = nan;
            app.rpmBuf(:)    = nan;
            app.errBuf(:)    = nan;
            app.pwmBuf(:)    = nan;

            % Reset saved stop cutoff
            app.saveEndIndex = NaN;

            if ~isempty(app.lineTarget) && isvalid(app.lineTarget)
                set(app.lineTarget, "XData", app.tBuf, "YData", app.targetBuf);
            end
            if ~isempty(app.lineRPM) && isvalid(app.lineRPM)
                set(app.lineRPM, "XData", app.tBuf, "YData", app.rpmBuf);
            end
            if ~isempty(app.lineErr) && isvalid(app.lineErr)
                set(app.lineErr, "XData", app.tBuf, "YData", app.errBuf);
            end
            if ~isempty(app.linePWM) && isvalid(app.linePWM)
                set(app.linePWM, "XData", app.tBuf, "YData", app.pwmBuf);
            end

            drawnow;
        end

        function pwmOut = limitPwmSlew(app, pwmIn, dt)
            % Limit PWM change rate for smoother acceleration and deceleration

            pwmIn = max(app.PWM_MIN, min(app.PWM_MAX, pwmIn));
            last  = max(app.PWM_MIN, min(app.PWM_MAX, app.lastPWM));

            stepUp   = app.pwmSlewUp * dt;
            stepDown = app.pwmSlewDown * dt;

            if pwmIn > last
                pwmOut = min(pwmIn, last + stepUp);
            else
                pwmOut = max(pwmIn, last - stepDown);
            end

            pwmOut = round(pwmOut);
        end

        function updatePidSpinnerState(app)
            if app.Running
                state = "off";
            else
                state = "on";
            end

            if ~isempty(app.KpSpinner) && isvalid(app.KpSpinner)
                app.KpSpinner.Enable = state;
            end

            if ~isempty(app.KiSpinner) && isvalid(app.KiSpinner)
                app.KiSpinner.Enable = state;
            end

            if ~isempty(app.KdSpinner) && isvalid(app.KdSpinner)
                app.KdSpinner.Enable = state;
            end
        end

        function saveResults(app)
            % Save current plotted data to Results folder:
            % 1) PNG image of the current graph
            % 2) Excel file with table data
            % 3) Excel chart generated from the saved data

            try
                % -----------------------------
                % Validate data
                % -----------------------------
                validIdx = ~isnan(app.tBuf) & ~isnan(app.targetBuf) & ...
                    ~isnan(app.rpmBuf) & ~isnan(app.errBuf) & ~isnan(app.pwmBuf);

                % If stop button was pressed before saving, keep only samples up to the
                % last valid point that existed before stop forced PWM to zero
                if ~isnan(app.saveEndIndex)
                    trimmedIdx = false(size(validIdx));
                    trimmedIdx(1:app.saveEndIndex) = validIdx(1:app.saveEndIndex);
                    validIdx = trimmedIdx;
                end

                if ~any(validIdx)
                    uialert(app.UIFigure, 'No data available to save.', 'Save Data');
                    return;
                end

                tData      = app.tBuf(validIdx).';
                targetData = app.targetBuf(validIdx).';
                rpmData    = app.rpmBuf(validIdx).';
                errData    = app.errBuf(validIdx).';
                pwmData    = app.pwmBuf(validIdx).';

                % -----------------------------
                % Create Results folder
                % -----------------------------
                appFolder = fileparts(mfilename('fullpath'));
                resultsFolder = fullfile(appFolder, 'Results');

                if ~exist(resultsFolder, 'dir')
                    mkdir(resultsFolder);
                end

                % -----------------------------
                % Build file names
                % -----------------------------
                timeStamp = char(datetime("now","Format","yyyy-MM-dd_HH-mm-ss"));
                baseName  = ['MotorResponse_' timeStamp];

                pngFile   = fullfile(resultsFolder, [baseName '.png']);
                excelFile = fullfile(resultsFolder, [baseName '.xlsx']);

                % -----------------------------
                % Save PNG from current data
                % -----------------------------
                tempFig = figure('Visible', 'off');
                tempAx = axes(tempFig);
                hold(tempAx, 'on');
                grid(tempAx, 'on');

                plot(tempAx, tData, targetData, 'LineWidth', 1.5);
                plot(tempAx, tData, rpmData,    'LineWidth', 1.5);
                plot(tempAx, tData, errData,    'LineWidth', 1.5);
                plot(tempAx, tData, pwmData,    'LineWidth', 1.5);

                title(tempAx, 'Motor Response');
                xlabel(tempAx, 'Time (s)');
                ylabel(tempAx, 'RPM / PWM');
                legend(tempAx, {'Reference','Actual RPM','Error','PWM'}, 'Location', 'best');
                hold(tempAx, 'off');

                exportgraphics(tempAx, pngFile, 'Resolution', 300);
                close(tempFig);

                % -----------------------------
                % Prepare table for Excel
                % -----------------------------
                T = table(tData, targetData, rpmData, errData, pwmData, ...
                    'VariableNames', {'Time_s', 'Reference_RPM', 'Actual_RPM', 'Error_RPM', 'PWM'});

                writetable(T, excelFile, 'Sheet', 'Data', 'Range', 'A1');

                % -----------------------------
                % Create Excel chart using ActiveX
                % -----------------------------
                try
                    excel = actxserver('Excel.Application');
                    excel.Visible = false;
                    wb = excel.Workbooks.Open(excelFile);
                    ws = wb.Worksheets.Item('Data');

                    % Add chart object
                    chartObj = ws.ChartObjects.Add(420, 20, 500, 300);
                    chart = chartObj.Chart;

                    % Chart type: line
                    chart.ChartType = 4; % xlLine

                    % Data range
                    lastRow = size(T, 1) + 1;
                    xRange = ws.Range(['A2:A' num2str(lastRow)]);

                    % Series 1: Reference
                    s1 = chart.SeriesCollection.NewSeries;
                    s1.Name = 'Reference';
                    s1.XValues = xRange;
                    s1.Values = ws.Range(['B2:B' num2str(lastRow)]);

                    % Series 2: Actual RPM
                    s2 = chart.SeriesCollection.NewSeries;
                    s2.Name = 'Actual RPM';
                    s2.XValues = xRange;
                    s2.Values = ws.Range(['C2:C' num2str(lastRow)]);

                    % Series 3: Error
                    s3 = chart.SeriesCollection.NewSeries;
                    s3.Name = 'Error';
                    s3.XValues = xRange;
                    s3.Values = ws.Range(['D2:D' num2str(lastRow)]);

                    % Series 4: PWM
                    s4 = chart.SeriesCollection.NewSeries;
                    s4.Name = 'PWM';
                    s4.XValues = xRange;
                    s4.Values = ws.Range(['E2:E' num2str(lastRow)]);

                    % Titles
                    chart.HasTitle = true;
                    chart.ChartTitle.Text = 'Motor Response';

                    chart.Axes(1).HasTitle = true;
                    chart.Axes(1).AxisTitle.Text = 'Time (s)';

                    chart.Axes(2).HasTitle = true;
                    chart.Axes(2).AxisTitle.Text = 'RPM / PWM';

                    wb.Save;
                    wb.Close(false);
                    excel.Quit;

                    delete(chart);
                    delete(chartObj);
                    delete(ws);
                    delete(wb);
                    delete(excel);

                catch
                    % If Excel ActiveX fails, keep the data table saved at least
                end

                uialert(app.UIFigure, ...
                    ['Data saved successfully in:' newline resultsFolder], ...
                    'Save Data', ...
                    'Icon', 'success');

            catch ME
                uialert(app.UIFigure, ...
                    ['Failed to save data.' newline newline ME.message], ...
                    'Save Error');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TsTarget = app.SAMPLE_MS / 1000;

            % Derived CPR
            if app.RPM_AT_OUTPUT
                app.CPR_TOTAL = app.CPR_MOTOR_4X * app.GEAR_RATIO;
            else
                app.CPR_TOTAL = app.CPR_MOTOR_4X;
            end

            % --- RPM target spinner ---
            app.RPMTargetSpinner.Limits = [0 80];
            app.RPMTargetSpinner.Step   = 1;
            app.RPMTargetSpinner.Value  = app.SETPOINT_RPM;
            app.SETPOINT_RPM            = app.RPMTargetSpinner.Value;

            % --- PID spinners linked to internal variables ---
            app.KpSpinner.Step  = 0.1;
            app.KiSpinner.Step  = 0.05;
            app.KdSpinner.Step  = 0.01;

            app.KpSpinner.Value = app.Kp;
            app.KiSpinner.Value = app.Ki;
            app.KdSpinner.Value = app.Kd;

            % Direction from UI
            app.forwardDir = strcmpi(app.DirectionDropDown.Value,"Forward");

            % Text indicators init
            app.ActualRPMLabel.Text = "Actual: -- RPM";
            app.ErrorLabel.Text     = "Error: -- RPM";
            app.PWMLabel.Text       = "PWM: -- / 255";

            % Start NOT connected
            app.hwConnected = false;
            app.a   = [];
            app.enc = [];
            app.lastCount = 0;

            % Create timer (but don't start)
            app.tmr = timer( ...
                "ExecutionMode","fixedRate", ...
                "Period", app.TsTarget, ...
                "BusyMode","drop", ...
                "TimerFcn", @(~,~) app.controlStep() );

            % Initialize Connection tab UI
            app.initConnectionTab();

            % Disable motor controls until connected
            app.RunButton.Enable  = "off";
            app.StopButton.Enable = "off";

            % Disable save button at startup
            app.SaveDataButton.Enable = "off";

            % PID spinners should be editable at startup because motor is stopped
            app.updatePidSpinnerState();

            % -----------------------
            % Plot init (buffers)
            % -----------------------
            app.tBuf      = nan(1, app.bufMax);
            app.targetBuf = nan(1, app.bufMax);
            app.rpmBuf    = nan(1, app.bufMax);
            app.errBuf    = nan(1, app.bufMax);
            app.pwmBuf    = nan(1, app.bufMax);

            % Axes setup
            cla(app.UIAxes);
            grid(app.UIAxes, "on");
            app.UIAxes.XLabel.String = "Time (s)";
            app.UIAxes.YLabel.String = "RPM / PWM";

            hold(app.UIAxes, "on");
            app.lineTarget = plot(app.UIAxes, app.tBuf, app.targetBuf, "-");
            app.lineRPM    = plot(app.UIAxes, app.tBuf, app.rpmBuf, "-");
            app.lineErr    = plot(app.UIAxes, app.tBuf, app.errBuf, "-");
            app.linePWM    = plot(app.UIAxes, app.tBuf, app.pwmBuf, "-");
            legend(app.UIAxes, {"Target","Actual","Error","PWM"}, "Location","northeast");
            hold(app.UIAxes, "off");
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.forwardDir = strcmpi(app.DirectionDropDown.Value, "Forward");

            if app.Running
                app.applyMotorPWM(0);
                pause(0.05);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~app.hwConnected
                fprintf(2,"[WARN] Cannot run: Arduino not connected.\n");
                return;
            end

            if app.Running
                return;
            end

            app.stopFlag = false;
            app.Running = true;

            % Enable save button once a run starts
            app.SaveDataButton.Enable = "on";

            % Lock PID spinners while motor is running
            app.updatePidSpinnerState();

            % Reset timing
            app.t0 = tic;
            app.tPrev = toc(app.t0);

            % Reset PID states
            app.firstSample = true;
            app.integral = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt = 0;

            % Reset save cutoff
            app.saveEndIndex = NaN;

            % Reset encoder baseline
            if app.hwConnected && ~isempty(app.enc)
                app.lastCount = readCount(app.enc);
            else
                app.lastCount = 0;
            end

            % UI enable/disable
            app.RunButton.Enable = "off";
            app.StopButton.Enable = "on";

            % Clear plots before starting
            app.clearPlots();

            % Keep save enabled after clearing because this is a valid new run
            app.SaveDataButton.Enable = "on";

            % Ensure PWM starts from 0
            app.lastPWM = 0;
            app.applyMotorPWM(0);
            pause(0.05);

            start(app.tmr);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopNow();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopNow();

            % Clean timer
            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    delete(app.tmr);
                catch
                end
            end

            delete(app.UIFigure);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connectArduino();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnectArduino();
        end

        % Button pushed function: TestBlinkD13Button
        function TestBlinkD13ButtonPushed(app, event)
            app.testConnection();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshPorts();
        end

        % Value changed function: RPMTargetSpinner
        function RPMTargetSpinnerValueChanged(app, event)
            app.SETPOINT_RPM = app.RPMTargetSpinner.Value;

            % Clamp to valid range
            app.SETPOINT_RPM = max(0, app.SETPOINT_RPM);

            % Reset PID states if target is near zero
            if app.SETPOINT_RPM < 0.5
                app.integral  = 0;
                app.prevError = 0;
                app.dFiltered = 0;
                app.SETPOINT_RPM = 0;
            end

        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            if app.Running
                app.KpSpinner.Value = app.Kp;
                return;
            end

            app.Kp = app.KpSpinner.Value;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            if app.Running
                app.KiSpinner.Value = app.Ki;
                return;
            end

            app.Ki = app.KiSpinner.Value;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            if app.Running
                app.KdSpinner.Value = app.Kd;
                return;
            end

            app.Kd = app.KdSpinner.Value;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.saveResults();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.StatusPanel = uipanel(app.ConnectionTab);
            app.StatusPanel.Title = 'Status';
            app.StatusNotconnectedLabel = uilabel(app.StatusPanel);
            app.StatusNotconnectedLabel.Text = 'Status: Not connected';
            app.StatusLampLabel = uilabel(app.StatusPanel);
            app.StatusLampLabel.Text = 'Status Lamp';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.ConnectionPanel = uipanel(app.ConnectionTab);
            app.ConnectionPanel.Title = 'Connection Panel';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.TestingPanel = uipanel(app.ConnectionTab);
            app.TestingPanel.Title = 'Testing Panel';
            app.RefreshButton = uibutton(app.TestingPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TestBlinkD13Button = uibutton(app.TestingPanel, 'push');
            app.TestBlinkD13Button.ButtonPushedFcn = createCallbackFcn(app, @TestBlinkD13ButtonPushed, true);
            app.TestBlinkD13Button.Text = 'Test (Blink D13)';
            app.ConfigrationPanel = uipanel(app.ConnectionTab);
            app.ConfigrationPanel.Title = 'Configration Panel';
            app.SelectYourPortLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourPortLabel.Text = 'Select Your Port:';
            app.SelectYourBoardLabel = uilabel(app.ConfigrationPanel);
            app.SelectYourBoardLabel.Text = 'Select Your Board:';
            app.AvailablePortsDropDownLabel = uilabel(app.ConfigrationPanel);
            app.AvailablePortsDropDownLabel.Text = 'Available Ports:';
            app.AvailablePortsDropDown = uidropdown(app.ConfigrationPanel);
            app.AvailablePortsDropDown.Items = {'COM7', 'COM8', 'COM5', '/dev/ttyUSB0'};
            app.AvailablePortsDropDown.Editable = 'on';
            app.AvailablePortsDropDown.Value = 'COM5';
            app.BoardLabel = uilabel(app.ConfigrationPanel);
            app.BoardLabel.Text = 'Boards';
            app.BoardsDropDown = uidropdown(app.ConfigrationPanel);
            app.BoardsDropDown.Items = {'Nano3', 'Uno'};
            app.BoardsDropDown.Editable = 'on';
            app.BoardsDropDown.Value = 'Nano3';
            app.MotorControlTab = uitab(app.TabGroup);
            app.MotorControlTab.Title = 'Motor Control';
            app.SystemControllingPanel = uipanel(app.MotorControlTab);
            app.SystemControllingPanel.Title = 'System Controlling';
            app.KpSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.SystemControllingPanel);
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.KiSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.SystemControllingPanel);
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.SystemControllingPanel);
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.RPMTargetSpinnerLabel = uilabel(app.SystemControllingPanel);
            app.RPMTargetSpinnerLabel.Text = 'RPM Target';
            app.RPMTargetSpinner = uispinner(app.SystemControllingPanel);
            app.RPMTargetSpinner.Limits = [0 80];
            app.RPMTargetSpinner.ValueChangedFcn = createCallbackFcn(app, @RPMTargetSpinnerValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.SystemControllingPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.SystemControllingPanel);
            app.DirectionDropDown.Items = {'Forward', 'Reverse'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = 'Forward';
            app.RunButton = uibutton(app.SystemControllingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.SystemControllingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SystemMonitoringPanel = uipanel(app.MotorControlTab);
            app.SystemMonitoringPanel.Title = 'System Monitoring';
            app.UIAxes = uiaxes(app.SystemMonitoringPanel);
            title(app.UIAxes, 'Motor Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'RPM/PWM')
            zlabel(app.UIAxes, 'Z')
            app.ActualRPMLabel = uilabel(app.SystemMonitoringPanel);
            app.ActualRPMLabel.Text = 'Actual RPM';
            app.ErrorLabel = uilabel(app.SystemMonitoringPanel);
            app.ErrorLabel.Text = 'Error';
            app.PWMLabel = uilabel(app.SystemMonitoringPanel);
            app.PWMLabel.Text = 'PWM';
            app.SaveDataButton = uibutton(app.SystemMonitoringPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controlling_DC_motor_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end