classdef BHA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusPanel                     matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image1                          matlab.ui.control.Image
        ParametersPanel                 matlab.ui.container.Panel
        MotorParametersPanel            matlab.ui.container.Panel
        RPM_motorSpinner                matlab.ui.control.Spinner
        RPMSpinnerLabel                 matlab.ui.control.Label
        K_motorSpinner                  matlab.ui.control.Spinner
        KSpinnerLabel                   matlab.ui.control.Label
        b_motorSpinner                  matlab.ui.control.Spinner
        bSpinner_2Label                 matlab.ui.control.Label
        J_motorSpinner                  matlab.ui.control.Spinner
        mSpinner_2Label_2               matlab.ui.control.Label
        L_motorSpinner                  matlab.ui.control.Spinner
        LSpinner_2Label                 matlab.ui.control.Label
        R_motorSpinner                  matlab.ui.control.Spinner
        RSpinner_2Label                 matlab.ui.control.Label
        BandBParametersPanel            matlab.ui.container.Panel
        StepAmplitude_b_and_bSpinner    matlab.ui.control.Spinner
        StepAmplitudeSpinnerLabel       matlab.ui.control.Label
        L_b_and_bSpinner                matlab.ui.control.Spinner
        LSpinnerLabel                   matlab.ui.control.Label
        R_b_and_bSpinner                matlab.ui.control.Spinner
        RSpinnerLabel                   matlab.ui.control.Label
        d_b_and_bSpinner                matlab.ui.control.Spinner
        dSpinnerLabel                   matlab.ui.control.Label
        g_b_and_bSpinner                matlab.ui.control.Spinner
        gSpinnerLabel                   matlab.ui.control.Label
        m_b_and_bSpinner                matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        CruiseParametersPanel           matlab.ui.container.Panel
        Speed_cruiseSpinner             matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        b_cruiseSpinner                 matlab.ui.control.Spinner
        bSpinnerLabel                   matlab.ui.control.Label
        m_cruiseSpinner                 matlab.ui.control.Spinner
        mSpinner_2Label                 matlab.ui.control.Label
        ResultsTable                    matlab.ui.control.Table
        OptimizationSettingsPanel       matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        MaxKdSpinner                    matlab.ui.control.Spinner
        MaxKdSpinnerLabel               matlab.ui.control.Label
        MaxKiSpinner                    matlab.ui.control.Spinner
        MaxKiSpinnerLabel               matlab.ui.control.Label
        MaxKpSpinner                    matlab.ui.control.Spinner
        MaxKpSpinnerLabel               matlab.ui.control.Label
        MaxIterationsSpinner            matlab.ui.control.Spinner
        MaxIterationsSpinnerLabel       matlab.ui.control.Label
        PopulationNumberofStarsSpinner  matlab.ui.control.Spinner
        PopulationNumberofStarsLabel    matlab.ui.control.Label
        SystemSelectionPanel            matlab.ui.container.Panel
        TypeofControllerDropDown        matlab.ui.control.DropDown
        TypeofControllerDropDownLabel   matlab.ui.control.Label
        SelectSystemDropDown            matlab.ui.control.DropDown
        SelectSystemDropDownLabel       matlab.ui.control.Label
        BlackHoleOptimizerLabel         matlab.ui.control.Label
        Cost                            matlab.ui.control.UIAxes
        System_Response                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ParamLabels = matlab.ui.control.Label.empty
        ParamFields = matlab.ui.control.NumericEditField.empty
        ParamNames cell = {}
        IsRunning = false
    end

    methods (Access = private)

        function hideAllStatusImages(app)
            % Hide all status images
            app.Image1.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image3.Visible = 'off';
            drawnow;
        end

        function showStatusImage(app, imgNum)
            % Show only one status image
            app.hideAllStatusImages();

            switch imgNum
                case 1
                    app.Image1.Visible = 'on';
                case 2
                    app.Image2.Visible = 'on';
                case 3
                    app.Image3.Visible = 'on';
            end

            drawnow;
        end

        function restartImage1Gif(app)
            % Restart Image1 GIF from the beginning
            gifSource = app.Image1.ImageSource;

            app.Image1.Visible = 'off';
            drawnow;

            app.Image1.ImageSource = '';
            drawnow;
            pause(0.05);

            app.Image1.ImageSource = gifSource;
            drawnow;
        end

        function playStage1ThenStage2(app)
            % Play Image1 GIF for a fixed duration, then switch to Image2
            app.restartImage1Gif();
            app.showStatusImage(1);

            pause(6);
            drawnow;

            app.showStatusImage(2);
        end

        function [VarMin, VarMax, gainNames] = getControllerInfo(app)

            controllerType = app.TypeofControllerDropDown.Value;

            maxKp = app.MaxKpSpinner.Value;
            maxKi = app.MaxKiSpinner.Value;
            maxKd = app.MaxKdSpinner.Value;

            if maxKp <= 0
                error('Max Kp must be greater than zero.');
            end

            switch upper(controllerType)

                case 'PI'
                    if maxKi <= 0
                        error('Max Ki must be greater than zero for PI.');
                    end

                    VarMin = [0 0];
                    VarMax = [maxKp maxKi];
                    gainNames = {'Kp','Ki'};

                case 'PD'
                    if maxKd <= 0
                        error('Max Kd must be greater than zero for PD.');
                    end

                    VarMin = [0 0];
                    VarMax = [maxKp maxKd];
                    gainNames = {'Kp','Kd'};

                case 'PID'
                    if maxKi <= 0 || maxKd <= 0
                        error('Max Ki and Max Kd must be greater than zero for PID.');
                    end

                    VarMin = [0 0 0];
                    VarMax = [maxKp maxKi maxKd];
                    gainNames = {'Kp','Ki','Kd'};

                otherwise
                    error('Unknown controller type.');
            end
        end

        function [systemFolder, model, initFcn, costFcnRaw, params] = getSelectedSystemData(app)

            systemName = app.SelectSystemDropDown.Value;

            switch systemName
                case 'Ball & Beam'
                    systemFolder = "Ball_and_beam";
                    model = "CLBandBBD";
                    initFcn = @init_ballandbeam;
                    costFcnRaw = @(K) cost_ballandbeam(K, model);

                    params.m        = app.m_b_and_bSpinner.Value;
                    params.R        = app.R_b_and_bSpinner.Value;
                    params.g        = app.g_b_and_bSpinner.Value;
                    params.L        = app.L_b_and_bSpinner.Value;
                    params.d        = app.d_b_and_bSpinner.Value;
                    params.step_amp = app.StepAmplitude_b_and_bSpinner.Value;

                case 'Cruise Control'
                    systemFolder = "cruise_control";
                    model = "CLCCBD";
                    initFcn = @init_cruise;
                    costFcnRaw = @(K) cost_cruise(K, model);

                    params.m = app.m_cruiseSpinner.Value;
                    params.b = app.b_cruiseSpinner.Value;
                    params.u = app.Speed_cruiseSpinner.Value;

                case 'Motor Speed Control'
                    systemFolder = "Motor_speed";
                    model = "CLMSBD";
                    initFcn = @init_motor;
                    costFcnRaw = @(K) cost_motor(K, model);

                    params.J        = app.J_motorSpinner.Value;
                    params.b        = app.b_motorSpinner.Value;
                    params.K        = app.K_motorSpinner.Value;
                    params.R        = app.R_motorSpinner.Value;
                    params.L        = app.L_motorSpinner.Value;
                    params.step_amp = app.RPM_motorSpinner.Value;

                otherwise
                    error('Please select a system first.');
            end
        end

        function Kfull = expandControllerGains(app, Kopt, controllerType)

            switch upper(controllerType)
                case 'PI'
                    Kfull = [Kopt(1) Kopt(2) 0];

                case 'PD'
                    Kfull = [Kopt(1) 0 Kopt(2)];

                case 'PID'
                    Kfull = [Kopt(1) Kopt(2) Kopt(3)];

                otherwise
                    error('Invalid controller type.');
            end
        end

        function cost = controllerCostWrapper(app, Kopt, controllerType, costFcnRaw)
            Kfull = expandControllerGains(app, Kopt, controllerType);
            cost = costFcnRaw(Kfull);
        end

        function [Ku, Tu] = estimateUltimateGain_app(app, model)

            Kp = 1;
            Ki = 0;
            Kd = 0;

            assignin('base','kp',Kp);
            assignin('base','ki',Ki);
            assignin('base','kd',Kd);

            maxIter = 50;
            dKp = 1;
            Ku = NaN;
            Tu = NaN;

            for k = 1:maxIter
                assignin('base','kp',Kp);
                assignin('base','ki',0);
                assignin('base','kd',0);

                simOut = sim(model, 'StopTime', '10');
                y = simOut.OutputResponse.Data;
                t = simOut.OutputResponse.Time;

                dy = diff(y);
                idx = find(dy(1:end-1).*dy(2:end) < 0);

                if numel(idx) >= 6
                    Ku = Kp;
                    crossingTimes = t(idx + 1);
                    Tu = mean(diff(crossingTimes)) * 2;
                    return;
                end

                Kp = Kp + dKp;
            end

            warning('No sustained oscillation detected. Using fallback Ku and Tu.');
            Ku = Kp;
            Tu = 1;
        end

        function assignSystemParamsToBase(app, params)

            names = fieldnames(params);

            for i = 1:numel(names)
                assignin('base', names{i}, params.(names{i}));
            end
        end

        function Kfull = znGainsByType_app(app, Ku, Tu, controllerType)

            [Kp, Ki, Kd] = zn_controller(Ku, Tu, controllerType);
            Kfull = [Kp Ki Kd];
        end

        function resetRunState(app)
            app.IsRunning = false;
            app.RunButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
            drawnow;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';

            app.hideAllStatusImages();
        end

        % Value changed function: SelectSystemDropDown
        function SelectSystemDropDownValueChanged(app, event)
            value = app.SelectSystemDropDown.Value;
            disp(value)

            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';

            if strcmp(value, 'Ball & Beam')
                app.BandBParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Cruise Control')
                app.CruiseParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Motor Speed Control')
                app.MotorParametersPanel.Visible = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Prevent double clicking and re-entry
            if app.IsRunning
                return;
            end

            app.IsRunning = true;
            app.RunButton.Enable = 'off';
            app.UIFigure.Pointer = 'watch';
            drawnow;

            cleanupObj = onCleanup(@() resetRunState(app));

            try
                % Reset images at the start of every run
                app.hideAllStatusImages();

                % Play stage 1 for one second, then switch to stage 2
                app.playStage1ThenStage2();

                % Read selections
                systemName     = app.SelectSystemDropDown.Value;
                controllerType = app.TypeofControllerDropDown.Value;

                if isempty(systemName)
                    uialert(app.UIFigure, 'Please select a system first.', 'Error');
                    return;
                end

                % Read optimization settings
                nPop  = app.PopulationNumberofStarsSpinner.Value;
                MaxIt = app.MaxIterationsSpinner.Value;

                if nPop <= 0 || MaxIt <= 0
                    uialert(app.UIFigure, 'Population and Max Iterations must be greater than zero.', 'Error');
                    return;
                end

                % Read controller bounds
                [VarMin, VarMax, ~] = app.getControllerInfo();

                % Read selected system data
                [systemFolder, model, initFcn, costFcnRaw, params] = app.getSelectedSystemData();

                % Main folder
                mainFolder = fileparts(mfilename('fullpath'));

                % Add initialization folder
                initFolder = fullfile(mainFolder, 'initialization');
                if ~isfolder(initFolder)
                    error('Initialization folder not found: %s', initFolder);
                end
                addpath(initFolder);

                % Add algorithm folder
                algorithmsFolder = fullfile(mainFolder, 'algorithms');
                if isfolder(algorithmsFolder)
                    addpath(genpath(algorithmsFolder));
                end

                % Select controller-specific model folder
                controllerFolder = "optimized - " + upper(controllerType);
                modelFolder = fullfile(mainFolder, controllerFolder, systemFolder);

                if ~isfolder(modelFolder)
                    error('Model folder not found: %s', modelFolder);
                end
                addpath(genpath(modelFolder));

                % Send parameters to base workspace
                app.assignSystemParamsToBase(params);

                % Run initialization function
                initFcn();

                % Assign simulation settings
                assignin('base', 'sim_time', 20);

                if ~evalin('base', 'exist(''ref'', ''var'')')
                    assignin('base', 'ref', 1);
                end

                % Close old models and load selected model
                bdclose('all');
                modelPath = fullfile(modelFolder, model + ".slx");

                if ~isfile(modelPath)
                    error('Model file not found: %s', modelPath);
                end

                load_system(modelPath);
                drawnow;

                % Build wrapped cost function
                costFcn = @(Kopt) app.controllerCostWrapper(Kopt, controllerType, costFcnRaw);

                % Run Black Hole Optimization
                [bestKopt_BHO, history] = blackHole(costFcn, nPop, MaxIt, VarMin, VarMax, controllerType, model);
                bestK_BHO = app.expandControllerGains(bestKopt_BHO, controllerType);

                assignin('base','kp',bestK_BHO(1));
                assignin('base','ki',bestK_BHO(2));
                assignin('base','kd',bestK_BHO(3));

                simOut_BHO = sim(model);
                resp_BHO = simOut_BHO.OutputResponse;
                info_BHO = stepinfo(resp_BHO.Data, resp_BHO.Time);

                % Run Ziegler-Nichols tuning
                [Ku, Tu] = app.estimateUltimateGain_app(model);
                bestK_ZN = app.znGainsByType_app(Ku, Tu, controllerType);

                assignin('base','kp',bestK_ZN(1));
                assignin('base','ki',bestK_ZN(2));
                assignin('base','kd',bestK_ZN(3));

                simOut_ZN = sim(model);
                resp_ZN = simOut_ZN.OutputResponse;
                info_ZN = stepinfo(resp_ZN.Data, resp_ZN.Time);

                % Plot BHO vs ZN response
                cla(app.System_Response, 'reset');
                plot(app.System_Response, resp_BHO.Time, resp_BHO.Data, 'LineWidth', 2);
                hold(app.System_Response, 'on');
                plot(app.System_Response, resp_ZN.Time, resp_ZN.Data, 'LineWidth', 2);
                hold(app.System_Response, 'off');
                grid(app.System_Response, 'on');
                xlabel(app.System_Response, 'Time (s)');
                ylabel(app.System_Response, 'Output');
                title(app.System_Response, 'BHO vs ZN Response');
                legend(app.System_Response, {'BHO','ZN'}, 'Location', 'best');

                % Plot cost per iteration
                cla(app.Cost, 'reset');

                iter = history.Iteration(:);
                costVals = history.Cost(:);

                plot(app.Cost, iter, costVals, '-o', 'LineWidth', 2, 'MarkerSize', 6);
                grid(app.Cost, 'on');
                xlabel(app.Cost, 'Iteration');
                ylabel(app.Cost, 'Cost');
                title(app.Cost, 'Cost per Iteration');
                xlim(app.Cost, [1 max(iter)]);
                drawnow;

                % Fill results table
                app.ResultsTable.ColumnName = {'Iteration','Kp','Ki','Kd','Cost','Overshoot','Rise Time'};
                app.ResultsTable.RowName = {};

                nRows = numel(history.Iteration);
                tableData = cell(nRows, 7);

                for i = 1:nRows
                    tableData{i,1} = history.Iteration(i);
                    tableData{i,2} = sprintf('%.2f', history.Kp(i));
                    tableData{i,3} = sprintf('%.2f', history.Ki(i));
                    tableData{i,4} = sprintf('%.2f', history.Kd(i));
                    tableData{i,5} = sprintf('%.3f', history.Cost(i));

                    if isnan(history.Overshoot(i))
                        tableData{i,6} = '-';
                    else
                        tableData{i,6} = sprintf('%.1f%%', history.Overshoot(i));
                    end

                    if isnan(history.RiseTime(i))
                        tableData{i,7} = '-';
                    else
                        tableData{i,7} = sprintf('%.2fs', history.RiseTime(i));
                    end
                end

                app.ResultsTable.Data = tableData;
                app.ResultsTable.ColumnWidth = {80, 70, 70, 70, 80, 90, 90};

                % Show stage 3 after all simulations and iterations finish
                app.showStatusImage(3);

                % Print final comparison
                disp('========== FINAL COMPARISON ==========');
                disp('BHO Gains:');
                disp(bestK_BHO);
                disp(info_BHO);

                disp('ZN Gains:');
                disp(bestK_ZN);
                disp(info_ZN);

            catch ME
                app.hideAllStatusImages();
                uialert(app.UIFigure, ME.message, 'Run Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.System_Response = uiaxes(app.UIFigure);
            title(app.System_Response, 'System')
            xlabel(app.System_Response, 'X')
            ylabel(app.System_Response, 'Y')
            zlabel(app.System_Response, 'Z')
            app.Cost = uiaxes(app.UIFigure);
            title(app.Cost, 'Cost')
            xlabel(app.Cost, 'X')
            ylabel(app.Cost, 'Y')
            zlabel(app.Cost, 'Z')
            app.BlackHoleOptimizerLabel = uilabel(app.UIFigure);
            app.BlackHoleOptimizerLabel.Text = '   Black Hole Optimizer';
            app.SystemSelectionPanel = uipanel(app.UIFigure);
            app.SystemSelectionPanel.Title = 'System Selection';
            app.SelectSystemDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.SelectSystemDropDownLabel.Text = 'Select System';
            app.SelectSystemDropDown = uidropdown(app.SystemSelectionPanel);
            app.SelectSystemDropDown.Items = {'', 'Ball & Beam', 'Cruise Control', 'Motor Speed Control'};
            app.SelectSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSystemDropDownValueChanged, true);
            app.SelectSystemDropDown.Value = '';
            app.TypeofControllerDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.TypeofControllerDropDownLabel.Text = 'Type of Controller';
            app.TypeofControllerDropDown = uidropdown(app.SystemSelectionPanel);
            app.TypeofControllerDropDown.Items = {'PI', 'PD', 'PID'};
            app.TypeofControllerDropDown.Value = 'PI';
            app.OptimizationSettingsPanel = uipanel(app.UIFigure);
            app.OptimizationSettingsPanel.Title = 'Optimization Settings';
            app.PopulationNumberofStarsLabel = uilabel(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsLabel.Text = 'Population (Number of Stars)';
            app.PopulationNumberofStarsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsSpinner.Step = 5;
            app.PopulationNumberofStarsSpinner.Limits = [0 Inf];
            app.MaxIterationsSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinnerLabel.Text = 'Max Iterations';
            app.MaxIterationsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinner.Step = 5;
            app.MaxIterationsSpinner.Limits = [0 Inf];
            app.MaxKpSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKpSpinnerLabel.Text = 'Max Kp';
            app.MaxKpSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKpSpinner.Step = 10;
            app.MaxKpSpinner.Limits = [0 Inf];
            app.MaxKpSpinner.Value = 500;
            app.MaxKiSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKiSpinnerLabel.Text = 'Max Ki';
            app.MaxKiSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKiSpinner.Step = 100;
            app.MaxKiSpinner.Limits = [0 Inf];
            app.MaxKiSpinner.Value = 500;
            app.MaxKdSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKdSpinnerLabel.Text = 'Max Kd';
            app.MaxKdSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKdSpinner.Step = 10;
            app.MaxKdSpinner.Limits = [0 Inf];
            app.MaxKdSpinner.Value = 500;
            app.RunButton = uibutton(app.OptimizationSettingsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.CruiseParametersPanel = uipanel(app.ParametersPanel);
            app.CruiseParametersPanel.Visible = 'off';
            app.mSpinner_2Label = uilabel(app.CruiseParametersPanel);
            app.mSpinner_2Label.Text = 'm';
            app.m_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.bSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.b_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.Speed_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.Speed_cruiseSpinner.Value = 1;
            app.BandBParametersPanel = uipanel(app.ParametersPanel);
            app.BandBParametersPanel.Visible = 'off';
            app.mSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.mSpinnerLabel.Text = 'm';
            app.m_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.gSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.gSpinnerLabel.Text = 'g';
            app.g_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.g_b_and_bSpinner.Value = 9.8;
            app.dSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.dSpinnerLabel.Text = 'd';
            app.d_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.RSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.RSpinnerLabel.Text = 'R';
            app.R_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.LSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.LSpinnerLabel.Text = 'L';
            app.L_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitudeSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.StepAmplitudeSpinnerLabel.Text = 'Step Amplitude';
            app.StepAmplitude_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitude_b_and_bSpinner.Value = 1;
            app.MotorParametersPanel = uipanel(app.ParametersPanel);
            app.MotorParametersPanel.Visible = 'off';
            app.RSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.RSpinner_2Label.Text = 'R';
            app.R_motorSpinner = uispinner(app.MotorParametersPanel);
            app.LSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.LSpinner_2Label.Text = 'L';
            app.L_motorSpinner = uispinner(app.MotorParametersPanel);
            app.mSpinner_2Label_2 = uilabel(app.MotorParametersPanel);
            app.mSpinner_2Label_2.Text = 'J';
            app.J_motorSpinner = uispinner(app.MotorParametersPanel);
            app.bSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.bSpinner_2Label.Text = 'b';
            app.b_motorSpinner = uispinner(app.MotorParametersPanel);
            app.KSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.KSpinnerLabel.Text = 'K';
            app.K_motorSpinner = uispinner(app.MotorParametersPanel);
            app.RPMSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.RPMSpinnerLabel.Text = 'RPM';
            app.RPM_motorSpinner = uispinner(app.MotorParametersPanel);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.Image1 = uiimage(app.StatusPanel);
            app.Image1.Visible = 'off';
            app.Image1.ImageSource = fullfile(pathToMLAPP, 'images', 'First.gif');
            app.Image2 = uiimage(app.StatusPanel);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'Second.gif');
            app.Image3 = uiimage(app.StatusPanel);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', '5.1.2 finish case 1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BHA_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end